classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        ReceiverUnitButton              matlab.ui.control.Button
        TransmitterUnitButton           matlab.ui.control.Button
        ReceiverPanel                   matlab.ui.container.Panel
        DETECTButton                    matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        DetectedDigitsLabel             matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TrLabel_2                       matlab.ui.control.Label
        TdLabel_2                       matlab.ui.control.Label
        ReadytorecordLabel              matlab.ui.control.Label
        TimeDomainSignalPanel_2         matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
        SpectrogramPanel_2              matlab.ui.container.Panel
        FFTSizesamplesEditField_2       matlab.ui.control.NumericEditField
        FFTSizesamplesEditField_2Label  matlab.ui.control.Label
        WindowAmplitudeEditField_2      matlab.ui.control.NumericEditField
        WindowAmplitudeEditField_2Label  matlab.ui.control.Label
        WindowShiftEditField_2          matlab.ui.control.NumericEditField
        WindowShiftEditField_2Label     matlab.ui.control.Label
        WindowLengthEditField_2         matlab.ui.control.NumericEditField
        WindowLengthEditField_2Label    matlab.ui.control.Label
        PlotButton_2                    matlab.ui.control.Button
        WindowTypeButtonGroup_2         matlab.ui.container.ButtonGroup
        TriangularFuncButton_2          matlab.ui.control.RadioButton
        RectangularFuncButton_2         matlab.ui.control.RadioButton
        GaussianFuncButton_2            matlab.ui.control.RadioButton
        HammingFuncButton_2             matlab.ui.control.RadioButton
        AudioAxes_2                     matlab.ui.control.UIAxes
        BackButton_2                    matlab.ui.control.Button
        Switch                          matlab.ui.control.Switch
        StopandPlotButton               matlab.ui.control.Button
        StartListeningButton            matlab.ui.control.Button
        TrEditField_2                   matlab.ui.control.NumericEditField
        TrEditField_2Label              matlab.ui.control.Label
        TdEditField_2                   matlab.ui.control.NumericEditField
        TdEditField_2Label              matlab.ui.control.Label
        SaveValuesButton_2              matlab.ui.control.Button
        TransmitterPanel                matlab.ui.container.Panel
        BackButton_3                    matlab.ui.control.Button
        AudioAnalysisPanel              matlab.ui.container.Panel
        SaveAudioButton                 matlab.ui.control.Button
        PlayAudioButton                 matlab.ui.control.Button
        NoinputLabel_3                  matlab.ui.control.Label
        NoinputLabel_2                  matlab.ui.control.Label
        NoinputLabel                    matlab.ui.control.Label
        TrLabel                         matlab.ui.control.Label
        TdLabel                         matlab.ui.control.Label
        InputNumberLabel                matlab.ui.control.Label
        InputPanel                      matlab.ui.container.Panel
        Button_11                       matlab.ui.control.Button
        Button_10                       matlab.ui.control.Button
        DButton                         matlab.ui.control.Button
        CButton                         matlab.ui.control.Button
        BButton                         matlab.ui.control.Button
        AButton                         matlab.ui.control.Button
        InputAmplitudeEditField         matlab.ui.control.NumericEditField
        InputAmplitudeEditFieldLabel    matlab.ui.control.Label
        TrEditField                     matlab.ui.control.NumericEditField
        TrEditFieldLabel                matlab.ui.control.Label
        TdEditField                     matlab.ui.control.NumericEditField
        TdEditFieldLabel                matlab.ui.control.Label
        SaveandPlotButton               matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
        Button_0                        matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        Button_9                        matlab.ui.control.Button
        Button_8                        matlab.ui.control.Button
        Button_7                        matlab.ui.control.Button
        Button_6                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        SpectrogramPanel                matlab.ui.container.Panel
        FFTSizesamplesEditField         matlab.ui.control.NumericEditField
        FFTSizesamplesEditFieldLabel    matlab.ui.control.Label
        WindowAmplitudeEditField        matlab.ui.control.NumericEditField
        WindowAmplitudeEditFieldLabel   matlab.ui.control.Label
        WindowShiftEditField            matlab.ui.control.NumericEditField
        WindowShiftEditFieldLabel       matlab.ui.control.Label
        WindowLengthEditField           matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel      matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        WindowTypeButtonGroup           matlab.ui.container.ButtonGroup
        TriangularFuncButton            matlab.ui.control.RadioButton
        RectangularFuncButton           matlab.ui.control.RadioButton
        GaussianFuncButton              matlab.ui.control.RadioButton
        HammingFuncButton               matlab.ui.control.RadioButton
        AudioAxes                       matlab.ui.control.UIAxes
        TimeDomainSignalPanel           matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        phoneNumber;
        phoneNumberSaved;
        tdSaved=0.2;
        trSaved=0.2;
        windowFunction='hamming';
        winLength=0.2;
        winShift=0.2;
        winAmplitude=1;
        fftSize=800;
        timeSignal;
        timeAmplitude=1;

        TdListening;
        TrListening;
        recorder;
        recordedData;
    end
    
    methods (Access = private)


        function signalVector = generateSignal(app,fl, fh, Td,timeAmp)
            % Generate time vector
            t = 0:1/8000:Td/8000;
        
            % Generate the signal vector
            signalVector = timeAmp * (sin(2*pi*fl*t) + sin(2*pi*fh*t));
        end
               

        % Callback function for the number buttons
        function numberButtonPressed(app, buttonNumber)
            % Get the current phone number
            currentNumber = app.phoneNumber;

            % Concatenate the pressed button's number to the current phone number
            app.phoneNumber = [currentNumber, buttonNumber];

            % Update the EditField
            app.EditField.Value = app.phoneNumber;
        end

        function concatenatedSignal = dtmfGenerator(app,x,Td,Tr)

            numbers = ['1' '2' '3' 'A' '4' '5' '6' 'B' '7' '8' '9' 'C' '*' '0' '#' 'D'];

            low_freq = [697 770 852 941];
            high_freq = [1209 1336 1477 1633];
        

            concatenatedSignal = zeros(1,length(x)*(Td+Tr)-Tr);


            for k=1:(length(x))

                index = find(numbers==x(k));

                index = int8(index);

                low_freq_index = idivide(index-1,int8(4));

                lf = low_freq(low_freq_index+1);

                high_freq_index = mod(index-1,int8(4));

                hf = high_freq(high_freq_index+1);

                sk = app.generateSignal(lf,hf,Td,app.timeAmplitude);

                zero_vec = zeros(1,Tr);

                if k<length(x)
                    component = [sk zero_vec];
                    concatenatedSignal((k-1)*(Td+Tr)+1:k*(Td+Tr)+1) = component; 
                else
                    component = sk;
                    concatenatedSignal((k-1)*(Td+Tr)+1:(k-1)*(Td+Tr)+Td+1) = component; 
                end
                
            end

            app.timeSignal = concatenatedSignal;
        end


        function [S, f, t] = mySTFT(app,x, windowFunc, lengthWin,shift,windowAmp,fftS,fs)
            % Parameters:
            % x: Input signal
            % window: Analysis window (e.g., hamming, hann)
            % overlap: Overlap percentage (0 to 1)
            % fs: Sampling frequency

            lengthWin =lengthWin*8000;
            shiftSize = shift*8000;


            if strcmpi(windowFunc, 'hamming')
                window = windowAmp.*hamming(lengthWin);
            elseif strcmpi(windowFunc, 'gaussian')
                window = windowAmp.*gausswin(lengthWin);
            elseif strcmpi(windowFunc, 'rectangular')
                window = windowAmp.*rectwin(lengthWin);
            elseif strcmpi(windowFunc, 'triangular')
                window = windowAmp.*triang(lengthWin);
            else
                error('Invalid window function. Choose hamming, gaussian, rectangular, or triangular.');
            end
        
            % Ensure the input signal is a column vector
            x = x(:);
        
            % Length of the signal
            N = length(x);
        
            % Length of the window
            L = length(window);
       
        
            % Calculate the number of time points
            numTimePoints = floor((N - L) / shiftSize) + 1;
        
            % Initialize STFT matrix
            S = zeros(fftS, numTimePoints);
        
            % Compute the STFT
            for i = 1:numTimePoints
                % Extract the current segment
                segment = x((i-1)*shiftSize + 1 : (i-1)*shiftSize + L);
        
                % Apply windowing
                segment = segment .* window;
        
                % Compute the FFT and store in the STFT matrix
                S(:, i) = fft(segment,fftS);
            end
        
            % Compute the time vector
            t = (0:numTimePoints - 1) * shiftSize / fs;
        
            % Compute the frequency vector
            f = linspace(0, fs/2, fftS/2 + 1);
        
            % Only keep the positive frequencies
            S = S(1:end/2 + 1, :);
        end

        
        function computeAndDisplaySTFT(app,audioData,sampleRate,axes)
            if ~isempty(audioData)
                % Compute STFT using spectrogram
                %[S, F, T] = app.mySpectrogramUs(audioData, 32, 32, 128,sampleRate,'rectangular',1);
                %[S, F, T] = app.mySpectrogramUs(audioData, app.winLength, app.winShift, app.fftSize, sampleRate,app.windowFunction,app.winAmplitude);
                [S, F, T] = app.mySTFT(audioData,app.windowFunction, app.winLength, app.winShift,app.winAmplitude, app.fftSize, sampleRate);
                %[S, F, T] = spectrogram(audioData, app.winLength,app.winLength-app.winShift , app.fftSize, sampleRate);
                % Display the STFT on the UIAxes

                if axes == 1

    
                    imagesc(app.AudioAxes, T, F, 10 * log10(abs(S)));
                    axis(app.AudioAxes, 'xy');
                    colormap(app.AudioAxes, 'jet');
                    xlabel(app.AudioAxes, 'Time (s)');
                    ylabel(app.AudioAxes, 'Frequency (Hz)');
                    title(app.AudioAxes, 'STFT of Audio Signal');
                    xlim(app.AudioAxes,[0 length(audioData)/sampleRate])

                else

                    imagesc(app.AudioAxes_2, T, F, 10 * log10(abs(S)));
                    axis(app.AudioAxes_2, 'xy');
                    colormap(app.AudioAxes_2, 'jet');
                    xlabel(app.AudioAxes_2, 'Time (s)');
                    ylabel(app.AudioAxes_2, 'Frequency (Hz)');
                    title(app.AudioAxes_2, 'STFT of Audio Signal');
                    xlim(app.AudioAxes_2,[0 length(audioData)/sampleRate])

                end

            else
                disp('Please record or import an audio file first.');
            end
        end

        function emptyVec = keyDetector(app,EncodedSignal,Td,Tr)

            %EncodedSignal = app.trimZerosWithThreshold(EncodedSignal,0.05);
            
            keysarray = ['1' '2' '3' 'A' '4' '5' '6' 'B' '7' '8' '9' 'C' '*' '0' '#' 'D'];

            low_frek = [697 770 852 941];
            high_frek = [1209 1336 1477 1633];

            detected_keys = zeros( 1 , int32(length(EncodedSignal)+8000*Tr) / int32(8000*(Td+Tr)) );

            emptyVec = '';

            for s=1:length(detected_keys)

                portion = EncodedSignal((s-1)*8000*(Td+Tr)+1:(s-1)*8000*(Td+Tr)+8000*(Td));

                for i=1:4
    
                    for j=1:4

                        klow = low_frek(i)/8000;
                        khigh = high_frek(j)/8000;

                        if ( abs(app.goertzel_my(portion,klow)) >= 100 && abs(app.goertzel_my(portion,khigh))>=100 )

                            
                            display((i-1)*4+j);

                            emptyVec = [emptyVec,keysarray((i-1)*4+j)];
                        end
                    end
                end
            end

        end
    
              
        function result = goertzel_my(app,x, k)
            % x: input signal
            % k: frequency index (normalized between 0 and 1)
            % N: length of the signal

            N=length(x);
            
            w = 2 * pi * k;
            coeff = 2 * cos(w);
            
            Q1 = 0;
            Q2 = 0;
            
            for n = 1:N
                Q0 = x(n) + coeff * Q1 - Q2;
                Q2 = Q1;
                Q1 = Q0;
            end
            
            result = Q1 - exp(-1j * w) * Q2;
        end

        function trimmedSignal = trimAndThreshold(app,signal, threshold, zeroThreshold)
        % signal: input signal
        % threshold: magnitude threshold for identifying significant samples
        % zeroThreshold: magnitude threshold for setting values to zero
        
        % Find the first and last indices with magnitude above the threshold
        firstNonZeroIndex = find(abs(signal) > threshold, 1, 'first');
        lastNonZeroIndex = find(abs(signal) > threshold, 1, 'last');
        
        % Check if the signal is all below the threshold
        if isempty(firstNonZeroIndex) || isempty(lastNonZeroIndex)
            trimmedSignal = [];  % Signal is below the threshold
        else
            % Trim the signal
            trimmedSignal = signal(firstNonZeroIndex:lastNonZeroIndex);
            
            % Set values below zeroThreshold to zero
         
            trimmedSignal(abs(trimmedSignal) < zeroThreshold) = 0;
        end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.numberButtonPressed('1');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.numberButtonPressed('2');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.numberButtonPressed('3');
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.numberButtonPressed('4');
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.numberButtonPressed('5');
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.numberButtonPressed('6');
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.numberButtonPressed('7');
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.numberButtonPressed('8');
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.numberButtonPressed('9');
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
       
            % Clear the phone number and update the EditField
            app.phoneNumber = '';
            app.EditField.Value = app.phoneNumber;
            app.NoinputLabel.Text = 'No input!';
            app.NoinputLabel_2.Text = 'No input!';
            app.NoinputLabel_3.Text = 'No input!';
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            % Remove the last character from the phone number
            if ~isempty(app.phoneNumber)
                app.phoneNumber = app.phoneNumber(1:end-1);
                app.EditField.Value = app.phoneNumber;
            end
        end

        % Button pushed function: Button_0
        function Button_0Pushed(app, event)
            app.numberButtonPressed('0');
        end

        % Button pushed function: SaveandPlotButton
        function SaveandPlotButtonPushed(app, event)
            
            app.phoneNumberSaved = app.phoneNumber;
            app.tdSaved = 8000*app.TdEditField.Value;
            app.trSaved = 8000*app.TrEditField.Value;
            app.NoinputLabel.Text = app.phoneNumberSaved;
            app.NoinputLabel_2.Text = num2str(app.tdSaved/8000);
            app.NoinputLabel_3.Text = num2str(app.trSaved/8000);

            dtmfSignal = app.dtmfGenerator(app.phoneNumberSaved,app.tdSaved,app.trSaved);
            samplesX = 0:1/8000:(length(dtmfSignal)-1)/8000;
            plot(app.UIAxes,samplesX,dtmfSignal);
            xlabel(app.UIAxes,'Sample Index');
            ylabel(app.UIAxes,'Amplitude');
            title(app.UIAxes,'Plot of Data over Samples');


        end

        % Value changed function: TdEditField
        function TdEditFieldValueChanged(app, event)
            value = app.TdEditField.Value;
            app.tdSaved = value;
        end

        % Value changed function: TrEditField
        function TrEditFieldValueChanged(app, event)
            value = app.TrEditField.Value;
            app.trSaved = value;
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            app.numberButtonPressed('A');
        end

        % Button pushed function: BButton
        function BButtonPushed(app, event)
            app.numberButtonPressed('B');
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            app.numberButtonPressed('C');
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            app.numberButtonPressed('D');
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            app.numberButtonPressed('*');
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.numberButtonPressed('#');
        end

        % Selection changed function: WindowTypeButtonGroup
        function WindowTypeButtonGroupSelectionChanged(app, event)
 
            selectedButton = app.WindowTypeButtonGroup.SelectedObject;
            if selectedButton.Text == "HammingFunc"
                app.windowFunction='hamming';
            elseif selectedButton.Text == "GaussianFunc"
                app.windowFunction='gaussian';
            elseif selectedButton.Text == "RectangularFunc"
                app.windowFunction='rectangular';
            elseif selectedButton.Text== "TriangularFunc"
                app.windowFunction ='triangular';
            end
            display(selectedButton);
        end

        % Value changed function: WindowLengthEditField
        function WindowLengthEditFieldValueChanged(app, event)
            value = app.WindowLengthEditField.Value;
            app.winLength = value;
        end

        % Value changed function: WindowShiftEditField
        function WindowShiftEditFieldValueChanged(app, event)
            value = app.WindowShiftEditField.Value;
            app.winShift = value;
        end

        % Value changed function: WindowAmplitudeEditField
        function WindowAmplitudeEditFieldValueChanged(app, event)
            value = app.WindowAmplitudeEditField.Value;
            app.winAmplitude = value;
        end

        % Value changed function: FFTSizesamplesEditField
        function FFTSizesamplesEditFieldValueChanged(app, event)
            value = app.FFTSizesamplesEditField.Value;
            app.fftSize = value;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.computeAndDisplaySTFT(app.timeSignal,8000,1);
        end

        % Button pushed function: TransmitterUnitButton
        function TransmitterUnitButtonPushed(app, event)
            app.TransmitterPanel.Visible = 'on';
            app.Panel.Visible='off';
        end

        % Button pushed function: ReceiverUnitButton
        function ReceiverUnitButtonPushed(app, event)
            app.ReceiverPanel.Visible = 'on';
            app.Panel.Visible='off';
        end

        % Button pushed function: BackButton_3
        function BackButton_3Pushed(app, event)
            app.TransmitterPanel.Visible = 'off';
            app.Panel.Visible = 'on';
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.ReceiverPanel.Visible='off';
            app.Panel.Visible='on';
        end

        % Value changed function: TdEditField_2
        function TdEditField_2ValueChanged(app, event)
            value = app.TdEditField_2.Value;
            app.TdListening = value;
        end

        % Value changed function: TrEditField_2
        function TrEditField_2ValueChanged(app, event)
            value = app.TrEditField_2.Value;
            app.TrListening = value;
        end

        % Button pushed function: StartListeningButton
        function StartListeningButtonPushed(app, event)
            % Start recording
            %app.recorder = audiorecorder;
            app.recorder = audiorecorder(8000,8,1); 
            pause(1);  % Adjust the duration as needed
            record(app.recorder);
            app.ReadytorecordLabel.Text = "You may speak, it's being recorded!";
            % Update UI or perform other actions as needed
            % (e.g., disable/enable buttons, update labels)
        end

        % Button pushed function: StopandPlotButton
        function StopandPlotButtonPushed(app, event)
            % Stop recording
            stop(app.recorder);
            % Get the recorded audio data
            audioData = getaudiodata(app.recorder);

            app.ReadytorecordLabel.Text = "Audio recorded with sampling rate: 8000";
            
            % Save the recorded audio to a file (you can customize the filename)
            app.recordedData=audioData;
            % Update UI or perform other actions as needed
            % (e.g., enable/disable buttons, update labels)

            app.recordedData = app.trimAndThreshold(app.recordedData,0.1,0.1);

            samplesX = 0:1/8000:(length(app.recordedData)-1)/8000;
            plot(app.UIAxes_2,samplesX,app.recordedData);
            xlabel(app.UIAxes_2,'Sample Index');
            ylabel(app.UIAxes_2,'Amplitude');
            title(app.UIAxes_2,'Plot of Data over Samples');


        end

        % Button pushed function: SaveValuesButton_2
        function SaveValuesButton_2Pushed(app, event)
            app.Label.Text = num2str(app.TdListening);
            app.Label_2.Text = num2str(app.TrListening);
        end

        % Selection changed function: WindowTypeButtonGroup_2
        function WindowTypeButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.WindowTypeButtonGroup_2.SelectedObject;
            if selectedButton.Text == "HammingFunc"
                app.windowFunction='hamming';
            elseif selectedButton.Text == "GaussianFunc"
                app.windowFunction='gaussian';
            elseif selectedButton.Text == "RectangularFunc"
                app.windowFunction='rectangular';
            elseif selectedButton.Text== "TriangularFunc"
                app.windowFunction ='triangular';
            end
            display(selectedButton);
        end

        % Value changed function: WindowLengthEditField_2
        function WindowLengthEditField_2ValueChanged(app, event)
            value = app.WindowLengthEditField_2.Value;
            app.winLength = value;
        end

        % Value changed function: WindowShiftEditField_2
        function WindowShiftEditField_2ValueChanged(app, event)
            value = app.WindowShiftEditField_2.Value;
            app.winShift = value;
        end

        % Value changed function: WindowAmplitudeEditField_2
        function WindowAmplitudeEditField_2ValueChanged(app, event)
            value = app.WindowAmplitudeEditField_2.Value;
            app.winAmplitude = value;
        end

        % Value changed function: FFTSizesamplesEditField_2
        function FFTSizesamplesEditField_2ValueChanged(app, event)
            value = app.FFTSizesamplesEditField_2.Value;
            app.fftSize = value;
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            app.computeAndDisplaySTFT(app.recordedData,8000,2);
        end

        % Button pushed function: DETECTButton
        function DETECTButtonPushed(app, event)
            detectedDigits = app.keyDetector(app.recordedData,app.TdListening,app.TrListening);

            app.Label_3.Text = detectedDigits;
        end

        % Button pushed function: SaveAudioButton
        function SaveAudioButtonPushed(app, event)
            audiowrite('/Users/yilmazguney/Desktop/METU-EE/FALL2023-2024/EE430/ProjectPart2/auio.wav', app.timeSignal, 8000);
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            if ~isempty(app.timeSignal)
                % Get the recorded audio data
                %play(app.recorder);
                audioData = app.timeSignal;
                playerObj=audioplayer(audioData,8000,8);
                % Play the recorded audio
                playblocking(playerObj);
            else
                % Display a message or handle the case where no audio is recorded
                disp('No audio recorded.');
                app.NosoundrecordedLabel.Visible = "on";
            end
              
        end

        % Value changed function: InputAmplitudeEditField
        function InputAmplitudeEditFieldValueChanged(app, event)
            value = app.InputAmplitudeEditField.Value;
            app.timeAmplitude=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TransmitterPanel = uipanel(app.UIFigure);
            app.TransmitterPanel.Title = 'Transmitter Panel';
            app.TransmitterPanel.Visible = 'off';
            app.TimeDomainSignalPanel = uipanel(app.TransmitterPanel);
            app.TimeDomainSignalPanel.Title = 'TimeDomainSignal';
            app.UIAxes = uiaxes(app.TimeDomainSignalPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SpectrogramPanel = uipanel(app.TransmitterPanel);
            app.SpectrogramPanel.Title = 'Spectrogram';
            app.AudioAxes = uiaxes(app.SpectrogramPanel);
            title(app.AudioAxes, 'Title')
            xlabel(app.AudioAxes, 'X')
            ylabel(app.AudioAxes, 'Y')
            zlabel(app.AudioAxes, 'Z')
            app.WindowTypeButtonGroup = uibuttongroup(app.SpectrogramPanel);
            app.WindowTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WindowTypeButtonGroupSelectionChanged, true);
            app.WindowTypeButtonGroup.Title = 'Window Type';
            app.HammingFuncButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.HammingFuncButton.Text = 'HammingFunc';
            app.HammingFuncButton.Value = true;
            app.GaussianFuncButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.GaussianFuncButton.Text = 'GaussianFunc';
            app.RectangularFuncButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.RectangularFuncButton.Text = 'RectangularFunc';
            app.TriangularFuncButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.TriangularFuncButton.Text = 'TriangularFunc';
            app.PlotButton = uibutton(app.SpectrogramPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.WindowLengthEditFieldLabel = uilabel(app.SpectrogramPanel);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.SpectrogramPanel, 'numeric');
            app.WindowLengthEditField.ValueChangedFcn = createCallbackFcn(app, @WindowLengthEditFieldValueChanged, true);
            app.WindowLengthEditField.Value = 0.2;
            app.WindowShiftEditFieldLabel = uilabel(app.SpectrogramPanel);
            app.WindowShiftEditFieldLabel.Text = 'Window Shift ';
            app.WindowShiftEditField = uieditfield(app.SpectrogramPanel, 'numeric');
            app.WindowShiftEditField.ValueChangedFcn = createCallbackFcn(app, @WindowShiftEditFieldValueChanged, true);
            app.WindowShiftEditField.Value = 0.2;
            app.WindowAmplitudeEditFieldLabel = uilabel(app.SpectrogramPanel);
            app.WindowAmplitudeEditFieldLabel.Text = 'Window Amplitude ';
            app.WindowAmplitudeEditField = uieditfield(app.SpectrogramPanel, 'numeric');
            app.WindowAmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @WindowAmplitudeEditFieldValueChanged, true);
            app.WindowAmplitudeEditField.Value = 1;
            app.FFTSizesamplesEditFieldLabel = uilabel(app.SpectrogramPanel);
            app.FFTSizesamplesEditFieldLabel.Text = 'FFT Size (samples)';
            app.FFTSizesamplesEditField = uieditfield(app.SpectrogramPanel, 'numeric');
            app.FFTSizesamplesEditField.ValueChangedFcn = createCallbackFcn(app, @FFTSizesamplesEditFieldValueChanged, true);
            app.FFTSizesamplesEditField.Value = 800;
            app.InputPanel = uipanel(app.TransmitterPanel);
            app.InputPanel.Title = 'Input';
            app.Button = uibutton(app.InputPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.InputPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.InputPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.InputPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.InputPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.InputPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.InputPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.InputPanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.InputPanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.EditField = uieditfield(app.InputPanel, 'text');
            app.EditField.Editable = 'off';
            app.ClearButton = uibutton(app.InputPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Button_0 = uibutton(app.InputPanel, 'push');
            app.Button_0.ButtonPushedFcn = createCallbackFcn(app, @Button_0Pushed, true);
            app.Button_0.Text = '0';
            app.BackButton = uibutton(app.InputPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.SaveandPlotButton = uibutton(app.InputPanel, 'push');
            app.SaveandPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandPlotButtonPushed, true);
            app.SaveandPlotButton.Text = 'Save and Plot';
            app.TdEditFieldLabel = uilabel(app.InputPanel);
            app.TdEditFieldLabel.Text = 'Td:';
            app.TdEditField = uieditfield(app.InputPanel, 'numeric');
            app.TdEditField.ValueChangedFcn = createCallbackFcn(app, @TdEditFieldValueChanged, true);
            app.TdEditField.Value = 0.2;
            app.TrEditFieldLabel = uilabel(app.InputPanel);
            app.TrEditFieldLabel.Text = 'Tr:';
            app.TrEditField = uieditfield(app.InputPanel, 'numeric');
            app.TrEditField.ValueChangedFcn = createCallbackFcn(app, @TrEditFieldValueChanged, true);
            app.TrEditField.Value = 0.2;
            app.InputAmplitudeEditFieldLabel = uilabel(app.InputPanel);
            app.InputAmplitudeEditFieldLabel.Text = 'Input Amplitude:';
            app.InputAmplitudeEditField = uieditfield(app.InputPanel, 'numeric');
            app.InputAmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @InputAmplitudeEditFieldValueChanged, true);
            app.InputAmplitudeEditField.Value = 1;
            app.AButton = uibutton(app.InputPanel, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A';
            app.BButton = uibutton(app.InputPanel, 'push');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.BButton.Text = 'B';
            app.CButton = uibutton(app.InputPanel, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.DButton = uibutton(app.InputPanel, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D';
            app.Button_10 = uibutton(app.InputPanel, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '*';
            app.Button_11 = uibutton(app.InputPanel, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '#';
            app.AudioAnalysisPanel = uipanel(app.TransmitterPanel);
            app.AudioAnalysisPanel.Title = 'Audio Analysis';
            app.InputNumberLabel = uilabel(app.AudioAnalysisPanel);
            app.InputNumberLabel.Text = 'Input Number:';
            app.TdLabel = uilabel(app.AudioAnalysisPanel);
            app.TdLabel.Text = 'Td:';
            app.TrLabel = uilabel(app.AudioAnalysisPanel);
            app.TrLabel.Text = 'Tr:';
            app.NoinputLabel = uilabel(app.AudioAnalysisPanel);
            app.NoinputLabel.Text = 'No input!';
            app.NoinputLabel_2 = uilabel(app.AudioAnalysisPanel);
            app.NoinputLabel_2.Text = 'No input!';
            app.NoinputLabel_3 = uilabel(app.AudioAnalysisPanel);
            app.NoinputLabel_3.Text = 'No input!';
            app.PlayAudioButton = uibutton(app.AudioAnalysisPanel, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.SaveAudioButton = uibutton(app.AudioAnalysisPanel, 'push');
            app.SaveAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAudioButtonPushed, true);
            app.SaveAudioButton.Text = 'Save Audio';
            app.BackButton_3 = uibutton(app.TransmitterPanel, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButton_3Pushed, true);
            app.BackButton_3.Text = 'Back';
            app.ReceiverPanel = uipanel(app.UIFigure);
            app.ReceiverPanel.Title = 'Receiver Panel';
            app.ReceiverPanel.Visible = 'off';
            app.SaveValuesButton_2 = uibutton(app.ReceiverPanel, 'push');
            app.SaveValuesButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveValuesButton_2Pushed, true);
            app.SaveValuesButton_2.Text = 'Save Values';
            app.TdEditField_2Label = uilabel(app.ReceiverPanel);
            app.TdEditField_2Label.Text = 'Td:';
            app.TdEditField_2 = uieditfield(app.ReceiverPanel, 'numeric');
            app.TdEditField_2.ValueChangedFcn = createCallbackFcn(app, @TdEditField_2ValueChanged, true);
            app.TrEditField_2Label = uilabel(app.ReceiverPanel);
            app.TrEditField_2Label.Text = 'Tr:';
            app.TrEditField_2 = uieditfield(app.ReceiverPanel, 'numeric');
            app.TrEditField_2.ValueChangedFcn = createCallbackFcn(app, @TrEditField_2ValueChanged, true);
            app.StartListeningButton = uibutton(app.ReceiverPanel, 'push');
            app.StartListeningButton.ButtonPushedFcn = createCallbackFcn(app, @StartListeningButtonPushed, true);
            app.StartListeningButton.Text = 'Start Listening';
            app.StopandPlotButton = uibutton(app.ReceiverPanel, 'push');
            app.StopandPlotButton.ButtonPushedFcn = createCallbackFcn(app, @StopandPlotButtonPushed, true);
            app.StopandPlotButton.Text = 'Stop and Plot';
            app.Switch = uiswitch(app.ReceiverPanel, 'slider');
            app.Switch.Items = {'Algorithm 1', 'Algorithm 2'};
            app.Switch.Value = 'Algorithm 1';
            app.BackButton_2 = uibutton(app.ReceiverPanel, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
            app.SpectrogramPanel_2 = uipanel(app.ReceiverPanel);
            app.SpectrogramPanel_2.Title = 'Spectrogram';
            app.AudioAxes_2 = uiaxes(app.SpectrogramPanel_2);
            title(app.AudioAxes_2, 'Title')
            xlabel(app.AudioAxes_2, 'X')
            ylabel(app.AudioAxes_2, 'Y')
            zlabel(app.AudioAxes_2, 'Z')
            app.WindowTypeButtonGroup_2 = uibuttongroup(app.SpectrogramPanel_2);
            app.WindowTypeButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @WindowTypeButtonGroup_2SelectionChanged, true);
            app.WindowTypeButtonGroup_2.Title = 'Window Type';
            app.HammingFuncButton_2 = uiradiobutton(app.WindowTypeButtonGroup_2);
            app.HammingFuncButton_2.Text = 'HammingFunc';
            app.HammingFuncButton_2.Value = true;
            app.GaussianFuncButton_2 = uiradiobutton(app.WindowTypeButtonGroup_2);
            app.GaussianFuncButton_2.Text = 'GaussianFunc';
            app.RectangularFuncButton_2 = uiradiobutton(app.WindowTypeButtonGroup_2);
            app.RectangularFuncButton_2.Text = 'RectangularFunc';
            app.TriangularFuncButton_2 = uiradiobutton(app.WindowTypeButtonGroup_2);
            app.TriangularFuncButton_2.Text = 'TriangularFunc';
            app.PlotButton_2 = uibutton(app.SpectrogramPanel_2, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.WindowLengthEditField_2Label = uilabel(app.SpectrogramPanel_2);
            app.WindowLengthEditField_2Label.Text = 'Window Length';
            app.WindowLengthEditField_2 = uieditfield(app.SpectrogramPanel_2, 'numeric');
            app.WindowLengthEditField_2.ValueChangedFcn = createCallbackFcn(app, @WindowLengthEditField_2ValueChanged, true);
            app.WindowLengthEditField_2.Value = 0.2;
            app.WindowShiftEditField_2Label = uilabel(app.SpectrogramPanel_2);
            app.WindowShiftEditField_2Label.Text = 'Window Shift ';
            app.WindowShiftEditField_2 = uieditfield(app.SpectrogramPanel_2, 'numeric');
            app.WindowShiftEditField_2.ValueChangedFcn = createCallbackFcn(app, @WindowShiftEditField_2ValueChanged, true);
            app.WindowShiftEditField_2.Value = 0.2;
            app.WindowAmplitudeEditField_2Label = uilabel(app.SpectrogramPanel_2);
            app.WindowAmplitudeEditField_2Label.Text = 'Window Amplitude ';
            app.WindowAmplitudeEditField_2 = uieditfield(app.SpectrogramPanel_2, 'numeric');
            app.WindowAmplitudeEditField_2.ValueChangedFcn = createCallbackFcn(app, @WindowAmplitudeEditField_2ValueChanged, true);
            app.WindowAmplitudeEditField_2.Value = 1;
            app.FFTSizesamplesEditField_2Label = uilabel(app.SpectrogramPanel_2);
            app.FFTSizesamplesEditField_2Label.Text = 'FFT Size (samples)';
            app.FFTSizesamplesEditField_2 = uieditfield(app.SpectrogramPanel_2, 'numeric');
            app.FFTSizesamplesEditField_2.ValueChangedFcn = createCallbackFcn(app, @FFTSizesamplesEditField_2ValueChanged, true);
            app.FFTSizesamplesEditField_2.Value = 800;
            app.TimeDomainSignalPanel_2 = uipanel(app.ReceiverPanel);
            app.TimeDomainSignalPanel_2.Title = 'TimeDomainSignal';
            app.UIAxes_2 = uiaxes(app.TimeDomainSignalPanel_2);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.ReadytorecordLabel = uilabel(app.ReceiverPanel);
            app.ReadytorecordLabel.Text = 'Ready to record!';
            app.TdLabel_2 = uilabel(app.ReceiverPanel);
            app.TdLabel_2.Text = 'Td:';
            app.TrLabel_2 = uilabel(app.ReceiverPanel);
            app.TrLabel_2.Text = 'Tr:';
            app.Label = uilabel(app.ReceiverPanel);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.ReceiverPanel);
            app.Label_2.Text = '0';
            app.DetectedDigitsLabel = uilabel(app.ReceiverPanel);
            app.DetectedDigitsLabel.Text = 'Detected Digits:';
            app.Label_3 = uilabel(app.ReceiverPanel);
            app.Label_3.Text = '000';
            app.DETECTButton = uibutton(app.ReceiverPanel, 'push');
            app.DETECTButton.ButtonPushedFcn = createCallbackFcn(app, @DETECTButtonPushed, true);
            app.DETECTButton.Text = 'DETECT';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.TransmitterUnitButton = uibutton(app.Panel, 'push');
            app.TransmitterUnitButton.ButtonPushedFcn = createCallbackFcn(app, @TransmitterUnitButtonPushed, true);
            app.TransmitterUnitButton.Text = 'Transmitter Unit';
            app.ReceiverUnitButton = uibutton(app.Panel, 'push');
            app.ReceiverUnitButton.ButtonPushedFcn = createCallbackFcn(app, @ReceiverUnitButtonPushed, true);
            app.ReceiverUnitButton.Text = 'Receiver Unit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end