classdef stftapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        BackButton_5                  matlab.ui.control.Button
        PlotButton                    matlab.ui.control.Button
        Settodefaultvalue1024Label_2  matlab.ui.control.Label
        Settodefaultvalue1Label_2     matlab.ui.control.Label
        Settodefaultvalue512Label_2   matlab.ui.control.Label
        Settodefaultvalue1024Label    matlab.ui.control.Label
        OKButton_5                    matlab.ui.control.Button
        OKButton_4                    matlab.ui.control.Button
        OKButton_3                    matlab.ui.control.Button
        OKButton_2                    matlab.ui.control.Button
        FFTSizesamplesEditField       matlab.ui.control.NumericEditField
        FFTSizesamplesEditFieldLabel  matlab.ui.control.Label
        WindowAmplitudeEditField      matlab.ui.control.NumericEditField
        WindowAmplitudeLabel          matlab.ui.control.Label
        WindowShiftEditField          matlab.ui.control.NumericEditField
        WindowShiftEditFieldLabel     matlab.ui.control.Label
        WindowLengthEditField         matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel    matlab.ui.control.Label
        WindowTypeButtonGroup         matlab.ui.container.ButtonGroup
        TriangularButton              matlab.ui.control.RadioButton
        RectangularButton             matlab.ui.control.RadioButton
        GaussianButton                matlab.ui.control.RadioButton
        HammingButton                 matlab.ui.control.RadioButton
        DefinePanel                   matlab.ui.container.Panel
        multiComponentPanel           matlab.ui.container.Panel
        OKButton_8                    matlab.ui.control.Button
        sinusPanel_2                  matlab.ui.container.Panel
        OKButton_9                    matlab.ui.control.Button
        PhaseShiftEditField_3         matlab.ui.control.NumericEditField
        PhaseShiftEditField_3Label    matlab.ui.control.Label
        FrequencyEditField_3          matlab.ui.control.NumericEditField
        FrequencyEditField_3Label     matlab.ui.control.Label
        AmplitudeEditField_3          matlab.ui.control.NumericEditField
        AmplitudeEditField_3Label     matlab.ui.control.Label
        Component1ParametersLabel     matlab.ui.control.Label
        NumberofComponentsEditField   matlab.ui.control.NumericEditField
        NumberofComponentsLabel       matlab.ui.control.Label
        windowedSinusPanel            matlab.ui.container.Panel
        CurrentWindowLength1Label     matlab.ui.control.Label
        CurrentStartingTime0Label     matlab.ui.control.Label
        CurrentPhase30Label           matlab.ui.control.Label
        CurrentFrequency1000Label     matlab.ui.control.Label
        CurrentAmplitude1Label        matlab.ui.control.Label
        WindowFunctionDropDown        matlab.ui.control.DropDown
        WindowFunctionLabel           matlab.ui.control.Label
        OKButton_7                    matlab.ui.control.Button
        WindowLengthEditField_2       matlab.ui.control.NumericEditField
        WindowLengthLabel             matlab.ui.control.Label
        StartingTimeEditField         matlab.ui.control.NumericEditField
        StartingTimeLabel             matlab.ui.control.Label
        PhaseShiftEditField_2         matlab.ui.control.NumericEditField
        PhaseShiftLabel               matlab.ui.control.Label
        FrequencyEditField_2          matlab.ui.control.NumericEditField
        FrequencyLabel                matlab.ui.control.Label
        AmplitudeEditField_2          matlab.ui.control.NumericEditField
        AmplitudeLabel                matlab.ui.control.Label
        WindowedSinusoidalFunctionParametersLabel  matlab.ui.control.Label
        sinusPanel                    matlab.ui.container.Panel
        Currentvalue30Label           matlab.ui.control.Label
        Currentvalue1000Label         matlab.ui.control.Label
        Currentvalue1Label            matlab.ui.control.Label
        OKButton_6                    matlab.ui.control.Button
        PhaseShiftEditField           matlab.ui.control.NumericEditField
        PhaseShiftLabel_2             matlab.ui.control.Label
        FrequencyEditField            matlab.ui.control.NumericEditField
        FrequencyLabel_2              matlab.ui.control.Label
        AmplitudeEditField            matlab.ui.control.NumericEditField
        AmplitudeLabel_2              matlab.ui.control.Label
        SinusoidalFunctionParametersLabel  matlab.ui.control.Label
        SignalDurationsecsEditField   matlab.ui.control.NumericEditField
        SignalDurationsecsEditFieldLabel  matlab.ui.control.Label
        SamplingRateEditField         matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel    matlab.ui.control.Label
        SpectrogramButton             matlab.ui.control.Button
        BackButton_4                  matlab.ui.control.Button
        Image5                        matlab.ui.control.Image
        whereLabel_2                  matlab.ui.control.Label
        Image4                        matlab.ui.control.Image
        whereLabel                    matlab.ui.control.Label
        Image3                        matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        MultiComponentSignalExpressionLabel  matlab.ui.control.Label
        WindowedSinusoidalSignalExpressionLabel  matlab.ui.control.Label
        SinusoidalSignalExpressionLabel  matlab.ui.control.Label
        MultiComponentFunctionButton  matlab.ui.control.Button
        WindowedSinusoidalButton      matlab.ui.control.Button
        SinusoidalButton              matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        STFTWindowPanel               matlab.ui.container.Panel
        STFToftheSignalLabel          matlab.ui.control.Label
        TimeDomainSignalLabel         matlab.ui.control.Label
        BackButton_3                  matlab.ui.control.Button
        AudioAxes                     matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        ImportMenuPanel               matlab.ui.container.Panel
        SpectogramButton              matlab.ui.control.Button
        BackButton_2                  matlab.ui.control.Button
        NowplayingtheaudioLabel       matlab.ui.control.Label
        PlayButton_2                  matlab.ui.control.Button
        sampleRateLabel               matlab.ui.control.Label
        SelectedfilepathLabel         matlab.ui.control.Label
        SelectButton                  matlab.ui.control.Button
        SecondMenu                    matlab.ui.container.Panel
        SampleRateLabel               matlab.ui.control.Label
        SpectogramButton_2            matlab.ui.control.Button
        OKButton                      matlab.ui.control.Button
        SampleRateEditField           matlab.ui.control.NumericEditField
        SampleRateLabel_2             matlab.ui.control.Label
        NosoundrecordedLabel          matlab.ui.control.Label
        recordingLabel                matlab.ui.control.Label
        PlayButton                    matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        StartRecordingButton          matlab.ui.control.Button
        BackButton                    matlab.ui.control.Button
        MainMenu                      matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        ImportButton                  matlab.ui.control.Button
        DefineButton                  matlab.ui.control.Button
        RecordButton                  matlab.ui.control.Button
    end

     
    properties (Access = public)
        recorder % Description
        AudioData;
        recordedData;
        definedData;
        timeDuration = 8;
        sampleRateInp=8000;
        sampleRateImported=8000;
        definedSampleRate=8000;
        windowSize=1024;
        windowShift=512;
        windowAmplitude=1;
        fftSize=1024;
        currentSampleRate=8000;
        windowFunction="hamming";

        sinusAmp = 1;
        sinusFreq = 1000;
        sinusPhase = 30;

        defineWindow='hamming';
        windowStart = 0;
        windowLen = 1;
        windowSinusAmp = 1;
        windowSinusFreq = 1000;
        windowSinusPhase = 30;

        numberComponent=1;
        componentCounter=1;

        ampArray=zeros(1,5);
        freqArray=zeros(1,5);
        phaseArray=zeros(1,5);

        componentAmp=1;
        componentFreq=1;
        componentPhase=0;
       

        state = "define";

        
    end
    
    methods (Access = public)


        function [S, F, T] = mySpectrogramUs(~,x, windowLennn, stepSize, fftSize, fs, windowFunc, windowAmp)
            overlap = windowLennn-stepSize;
            column = floor((length(x)-overlap)/stepSize);

         
            if mod(fftSize,2)==0
                rows = fftSize/2+1;
            else
                rows = (fftSize+1)/2;
            end

            S = zeros(rows, column);


            if strcmpi(windowFunc, 'hamming')
                window = windowAmp*hamming(windowLennn);
            elseif strcmpi(windowFunc, 'gaussian')
                window = windowAmp*gausswin(windowLennn);
            elseif strcmpi(windowFunc, 'rectangular')
                window = windowAmp*rectwin(windowLennn);
            elseif strcmpi(windowFunc, 'triangular')
                window = windowAmp*triang(windowLennn);
            else
                error('Invalid window function. Choose hamming, gaussian, rectangular, or triangular.');
            end

            for i=1:column

             
                display((i-1)*stepSize);
                display((i-1)*(stepSize)+windowLennn);
                display(fftSize);
                
                xsliced = x((i-1)*stepSize+1:(i-1)*(stepSize)+windowLennn);
                xwindowed = window.*xsliced;

                fftwindowed = fft(xwindowed,fftSize);

                spec_mag = abs(fftwindowed(1:rows));
                %spec_mag = spec_mag/max(spec_mag,[],'all');

                S(:, i) = spec_mag;

                T(i) = ((i-1)*stepSize+1)/fs;
              
            end

            F = linspace(0, fs/2, fftSize);

        end


        function computeAndDisplaySTFT(app,audioData,sampleRate)
            if ~isempty(audioData)
                % Compute STFT using spectrogram
                %[S, F, T] = app.mySpectrogramUs(audioData, 32, 32, 128,sampleRate,'rectangular',1);
                [S, F, T] = app.mySpectrogramUs(audioData, app.windowSize, app.windowShift, app.fftSize, sampleRate,app.windowFunction,app.windowAmplitude);
                %[SS, FF, TT] = spectrogram(app.AudioData, 256,0 , 1024, sampleRate);
                % Display the STFT on the UIAxes
                t = (0:length(audioData)-1) / sampleRate;
                plot(app.AudioAxes,t,audioData);
                axis(app.AudioAxes, 'xy');
                colormap(app.AudioAxes, 'jet');
                xlabel(app.AudioAxes, 'Time (s)');
                ylabel(app.AudioAxes, 'Audio Signal');
                title(app.AudioAxes, 'Time Domain Plot of Audio Signal');
                xlim(app.AudioAxes,[0 length(audioData)/sampleRate])

                imagesc(app.UIAxes, T, F, 10 * log10(abs(S)));
                axis(app.UIAxes, 'xy');
                colormap(app.UIAxes, 'jet');
                xlabel(app.UIAxes, 'Time (s)');
                ylabel(app.UIAxes, 'Frequency (Hz)');
                title(app.UIAxes, 'STFT of Audio Signal');
                xlim(app.UIAxes,[0 length(audioData)/sampleRate])
            else
                disp('Please record or import an audio file first.');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function openRecordScreen(app, event)
            % Display the second menu
            app.SampleRateEditField.Value = 8000;
            app.MainMenu.Visible = 'off';
            app.SecondMenu.Visible = 'on';
        end

        % Button pushed function: BackButton
        function backToMainMenu(app, event)
            app.SecondMenu.Visible = 'off';
            app.MainMenu.Visible = "on";
            app.NosoundrecordedLabel.Visible = "off";
            app.recordingLabel.Text = "Before start recording, you may pick the sampling rate above";
        end

        % Button pushed function: StartRecordingButton
        function startRecordingButtonPushed(app, event)
            % Start recording
            %app.recorder = audiorecorder;
            app.recorder = audiorecorder(app.sampleRateInp,8,1); 
            app.currentSampleRate = app.sampleRateInp;
            
            pause(1);  % Adjust the duration as needed
            record(app.recorder);
            app.recordingLabel.Text = "You may speak, it's being recorded!";
            app.NosoundrecordedLabel.Visible = "off";
            
            % Update UI or perform other actions as needed
            % (e.g., disable/enable buttons, update labels)
        end

        % Button pushed function: StopButton
        function stopButtonPushed(app, event)
            % Stop recording
            stop(app.recorder);
            % Get the recorded audio data
            audioData = getaudiodata(app.recorder);

            app.recordingLabel.Text = ["Audio recorded with sampling rate: " string(app.sampleRateInp)];
            
            % Save the recorded audio to a file (you can customize the filename)
            app.recordedData=audioData;
            audiowrite('recorded_audio.wav', audioData, app.recorder.SampleRate);
 
            % Update UI or perform other actions as needed
            % (e.g., enable/disable buttons, update labels)
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % Check if there is recorded audio
            if app.recorder.TotalSamples > 0
                total_samp = app.recorder.TotalSamples;
                str_total = string(total_samp);
                % Get the recorded audio data
                %play(app.recorder);
                app.NosoundrecordedLabel.Text = "Now playing the audio...";
                app.NosoundrecordedLabel.Visible = "on";
                playerObj=audioplayer(app.recordedData,app.recorder.SampleRate,8);
                % Play the recorded audio
                playblocking(playerObj);
                app.NosoundrecordedLabel.Text = "Audio played, number of samples: " + str_total;

            else
                % Display a message or handle the case where no audio is recorded
                disp('No audio recorded.');
                app.NosoundrecordedLabel.Visible = "on";
            end
              
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            app.MainMenu.Visible='off';
            app.ImportMenuPanel.Visible='on';
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            [fileName, filePath] = uigetfile({'*.wav;*.mp3', 'Audio Files (*.wav, *.mp3)'}, 'Select Audio File');
            if isequal(fileName, 0)
                % User clicked Cancel
                return;
            end
        
            % Display the selected file path or perform any other actions
            selectedFilePath = fullfile(filePath, fileName);
            selectedFileList = strsplit(selectedFilePath,"/");
            selectedFileName = selectedFileList(end);
            app.SelectedfilepathLabel.Text = "Selected file path: " + selectedFileName;
            app.SelectedfilepathLabel.Visible = "on";

            try
                [app.AudioData, sampleRate] = audioread(selectedFilePath);
                app.sampleRateImported = sampleRate;
                app.currentSampleRate = app.sampleRateImported;

                % Now app.AudioData contains the audio data
                % You can use it for further processing or analysis

                % Display some information (optional)
                app.sampleRateLabel.Text = ['Sample Rate: ' num2str(sampleRate)]; 
                app.sampleRateLabel.Visible = "on";
                app.PlayButton_2.Visible = "on";

            catch
                % Handle any errors that may occur during file reading
                app.sampleRateLabel.Text = "Error reading the file!";
                app.AudioData = [];
            end
            
            % Now you can use the selected file path in your app
            % For example, you can read the audio file using audioread(selectedFilePath)
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
            if ~isempty(app.AudioData)
                total_samp = length(app.AudioData);
                str_total = string(total_samp);
                % Get the recorded audio data
                %play(app.recorder);
                audioData = app.AudioData;
                app.NowplayingtheaudioLabel.Visible = "on";
                playerObj=audioplayer(audioData,app.sampleRateImported,8);
                % Play the recorded audio
                playblocking(playerObj);
                app.NosoundrecordedLabel.Text = "Audio played, number of samples: " + str_total;

            else
                % Display a message or handle the case where no audio is recorded
                disp('No audio recorded.');
                app.NosoundrecordedLabel.Visible = "on";
            end
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.MainMenu.Visible = "on";
            app.ImportMenuPanel.Visible = "off";
            app.NowplayingtheaudioLabel.Visible="off";
            app.PlayButton_2.Visible="off";
            app.sampleRateLabel.Visible="off";
            app.SelectedfilepathLabel.Text= "";
            app.SelectedfilepathLabel.Visible = "off";
        end

        % Value changed function: SampleRateEditField
        function SampleRateEditFieldValueChanged(app, event)
            app.sampleRateInp = app.SampleRateEditField.Value;
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if app.sampleRateInp==8000
                app.recordingLabel.Text = "Default sample rate: 8000";
            else
                app.recordingLabel.Text = ["New sample rate: " string(app.sampleRateInp)];
            end
        end

        % Button pushed function: SpectogramButton
        function SpectogramButtonPushed(app, event)
            app.state = "import";
            app.Panel.Visible = "on";
            app.ImportMenuPanel.Visible = "off";
            
        end

        % Button pushed function: SpectogramButton_2
        function SpectogramButton_2Pushed(app, event)
            app.state = "record";
            app.SecondMenu.Visible = "off";
            app.Panel.Visible = "on";
        end

        % Value changed function: WindowLengthEditField
        function WindowLengthEditFieldValueChanged(app, event)
            value = app.WindowLengthEditField.Value;
            app.windowSize = value;
        end

        % Value changed function: WindowShiftEditField
        function WindowShiftEditFieldValueChanged(app, event)
            value = app.WindowShiftEditField.Value;
            app.windowShift = value;
        end

        % Value changed function: WindowAmplitudeEditField
        function WindowAmplitudeEditFieldValueChanged(app, event)
            value = app.WindowAmplitudeEditField.Value;
            app.windowAmplitude = value;
        end

        % Value changed function: FFTSizesamplesEditField
        function FFTSizesamplesEditFieldValueChanged(app, event)
            value = app.FFTSizesamplesEditField.Value;
            app.fftSize = value;
        end

        % Button pushed function: OKButton_2
        function OKButton_2Pushed(app, event)
            if app.windowSize ~= 1024
                app.Settodefaultvalue1024Label.Text = sprintf("New value: %d",app.windowSize);
            else
                app.Settodefaultvalue1024Label.Text = sprintf("Set to default value: %d",app.windowSize);
            end
        end

        % Button pushed function: OKButton_3
        function OKButton_3Pushed(app, event)
            if app.windowShift ~= 512
                app.Settodefaultvalue512Label_2.Text = sprintf("New value: %d",app.windowShift);
            else
                app.Settodefaultvalue512Label_2.Text = sprintf("Set to default value: %d",app.windowShift);
            end
        end

        % Button pushed function: OKButton_4
        function OKButton_4Pushed(app, event)
            if app.windowAmplitude ~= 1
                app.Settodefaultvalue1Label_2.Text = sprintf("New value: %d",app.windowAmplitude);
            else
                app.Settodefaultvalue1Label_2.Text = sprintf("Set to default value: %d",app.windowAmplitude);
            end
        end

        % Button pushed function: OKButton_5
        function OKButton_5Pushed(app, event)
            if app.fftSize ~= 1024
                app.Settodefaultvalue1024Label_2.Text = sprintf("New value: %d",app.fftSize);
            else
                app.Settodefaultvalue1024Label_2.Text = sprintf("Set to default value: %d",app.fftSize);
            end
           
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.Panel.Visible = "off";
        

            if app.state=='import'
                app.computeAndDisplaySTFT(app.AudioData,app.sampleRateImported);
            elseif app.state=='record'
                app.computeAndDisplaySTFT(app.recordedData,app.sampleRateInp);
            else
                app.computeAndDisplaySTFT(app.definedData,app.definedSampleRate);
            end
                
            app.STFTWindowPanel.Visible = "on";
            app.AudioAxes.Visible = "on";
        end

        % Selection changed function: WindowTypeButtonGroup
        function WindowTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.WindowTypeButtonGroup.SelectedObject;
            if selectedButton == 0
                app.windowFunction='hamming';
            elseif selectedButton == 1
                app.windowFunction='gaussian';
            elseif selectedButton == 2
                app.windowFunction='rectangular';
            else
                app.windowFunction ='triangular';
            end
            display(selectedButton);
        end

        % Button pushed function: BackButton_3
        function BackButton_3Pushed(app, event)
            app.STFTWindowPanel.Visible='off';
            app.Panel.Visible='on';
        end

        % Button pushed function: BackButton_5
        function BackButton_5Pushed(app, event)
            app.Panel.Visible='off';
            if app.state=='record'
                app.SecondMenu.Visible='on';
            elseif app.state=='import'
                app.ImportMenuPanel.Visible='on';
            else
                app.DefinePanel.Visible='on';
            end
        end

        % Button pushed function: BackButton_4
        function BackButton_4Pushed(app, event)
            app.DefinePanel.Visible='off';
            app.MainMenu.Visible='on';
        end

        % Button pushed function: SinusoidalButton
        function SinusoidalButtonPushed(app, event)
            app.sinusPanel.Visible="on";
            app.windowedSinusPanel.Visible="off";
            app.multiComponentPanel.Visible="off";
            app.SpectrogramButton.Visible="on";
        end

        % Button pushed function: WindowedSinusoidalButton
        function WindowedSinusoidalButtonPushed(app, event)
            app.sinusPanel.Visible="off";
            app.windowedSinusPanel.Visible="on";
            app.multiComponentPanel.Visible="off";
            app.SpectrogramButton.Visible="on";
        end

        % Button pushed function: MultiComponentFunctionButton
        function MultiComponentFunctionButtonPushed(app, event)
            app.sinusPanel.Visible="off";
            app.windowedSinusPanel.Visible="off";
            app.multiComponentPanel.Visible="on";
            app.SpectrogramButton.Visible="off";
        end

        % Button pushed function: DefineButton
        function DefineButtonPushed(app, event)
            app.DefinePanel.Visible="on";
            app.MainMenu.Visible="off";
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            value = app.AmplitudeEditField.Value;
            app.sinusAmp = value;
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            app.sinusFreq = value;
        end

        % Value changed function: PhaseShiftEditField
        function PhaseShiftEditFieldValueChanged(app, event)
            value = app.PhaseShiftEditField.Value;
            app.sinusPhase = value;
        end

        % Button pushed function: OKButton_6
        function OKButton_6Pushed(app, event)
            app.Currentvalue1Label.Text = sprintf("Current value: %d",app.sinusAmp);
            app.Currentvalue1000Label.Text = sprintf("Current value: %d",app.sinusFreq);
            app.Currentvalue30Label.Text = sprintf("Current value: %d",app.sinusPhase);

            tseq = 0:(1/app.definedSampleRate):app.timeDuration;
            sinusoidalSignal = app.sinusAmp*cos(2*pi*app.sinusFreq*tseq+app.sinusPhase);
            app.definedData = sinusoidalSignal;
        end

        % Value changed function: AmplitudeEditField_2
        function AmplitudeEditField_2ValueChanged(app, event)
            value = app.AmplitudeEditField_2.Value;
            app.windowSinusAmp=value;
        end

        % Value changed function: FrequencyEditField_2
        function FrequencyEditField_2ValueChanged(app, event)
            value = app.FrequencyEditField_2.Value;
            app.windowSinusFreq = value;
        end

        % Value changed function: PhaseShiftEditField_2
        function PhaseShiftEditField_2ValueChanged(app, event)
            value = app.PhaseShiftEditField_2.Value;
            app.windowSinusPhase = value;
        end

        % Value changed function: StartingTimeEditField
        function StartingTimeEditFieldValueChanged(app, event)
            value = app.StartingTimeEditField.Value;
            app.windowStart = value;
        end

        % Value changed function: WindowLengthEditField_2
        function WindowLengthEditField_2ValueChanged(app, event)
            value = app.WindowLengthEditField_2.Value;
            app.windowLen = value;
        end

        % Button pushed function: OKButton_7
        function OKButton_7Pushed(app, event)
            app.CurrentWindowLength1Label.Text = sprintf("Current window length: %d",app.windowLen);
            app.CurrentStartingTime0Label.Text = sprintf("Current window start: %d",app.windowStart);
            app.CurrentPhase30Label.Text = sprintf("Current phase: %d",app.windowSinusPhase);
            app.CurrentAmplitude1Label.Text = sprintf("Current amplitude: %d",app.windowSinusAmp);
            app.CurrentFrequency1000Label.Text = sprintf("Current frequency: %d",app.windowSinusFreq);

            tseq = 0:(1/app.definedSampleRate):app.timeDuration;
            sinusoidalSignal = app.windowSinusAmp * cos(2*pi*app.windowSinusFreq*tseq + app.windowSinusPhase);

            display(length(tseq));

            if strcmpi(app.defineWindow, 'hamming')
                windower = hamming(app.windowLen*app.definedSampleRate);
            elseif strcmpi(app.defineWindow, 'gaussian')
                windower = gausswin(app.windowLen*app.definedSampleRate);
            elseif strcmpi(app.defineWindow, 'rectangular')
                windower = rectwin(app.windowLen*app.definedSampleRate);
            elseif strcmpi(app.defineWindow, 'triangular')
                windower = triang(app.windowLen*app.definedSampleRate);
            else
                error('Invalid window function. Choose hamming, gaussian, rectangular, or triangular.');
            end
            
            app.state = "define";

            startTime = app.windowStart;
            windowLength = app.windowLen;

            startIndex = find(tseq >= startTime, 1);
            endIndex = find(tseq >= (startTime + windowLength), 1);

            display((endIndex - startIndex))

            window = windower((endIndex - startIndex));

            windowedSignal = sinusoidalSignal(startIndex:endIndex) .* window;

            display(max(window));

            app.definedData = windowedSignal;

        end

        % Button pushed function: SpectrogramButton
        function SpectrogramButtonPushed(app, event)
            app.state = "define";
            app.Panel.Visible = "on";
            app.DefinePanel.Visible = "off";
        end

        % Value changed function: WindowFunctionDropDown
        function WindowFunctionDropDownValueChanged(app, event)
            value = app.WindowFunctionDropDown.Value;
            if value==0
                app.defineWindow='hamming';
            elseif value==1
                app.defineWindow='rectangular';
            elseif value==2
                app.defineWindow='gaussian';
            else
                app.defineWindow='triangular';
            end
        end

        % Value changed function: SamplingRateEditField
        function SamplingRateEditFieldValueChanged(app, event)
            value = app.SamplingRateEditField.Value;
            app.definedSampleRate = value;
        end

        % Value changed function: SignalDurationsecsEditField
        function SignalDurationsecsEditFieldValueChanged(app, event)
            value = app.SignalDurationsecsEditField.Value;
            app.timeDuration = value;
        end

        % Value changed function: NumberofComponentsEditField
        function NumberofComponentsEditFieldValueChanged(app, event)
            value = app.NumberofComponentsEditField.Value;
            app.numberComponent = value;
        end

        % Button pushed function: OKButton_8
        function OKButton_8Pushed(app, event)
            app.sinusPanel_2.Visible="on";
        end

        % Button pushed function: OKButton_9
        function OKButton_9Pushed(app, event)
            app.ampArray(app.componentCounter)=app.componentAmp;
            app.freqArray(app.componentCounter)=app.componentFreq;
            app.phaseArray(app.componentCounter)=app.componentPhase;
            app.componentCounter = app.componentCounter + 1;
            app.componentAmp=1;
            app.componentFreq=1;
            app.componentPhase=0;
            app.Component1ParametersLabel.Text = sprintf("Component %d Parameters",app.componentCounter);
            app.AmplitudeEditField_3.Value = 1;
            app.FrequencyEditField_3.Value = 1;
            app.PhaseShiftEditField_3.Value = 0;
            if app.componentCounter==app.numberComponent
                app.sinusPanel_2.Visible="off";
                app.SpectrogramButton.Visible="on";

                tseq = 0:(1/app.definedSampleRate):app.timeDuration;
                sinusoidalSignal = zeros(1,length(tseq));

                for k=1:app.componentCounter
                
                    sinSignal = app.ampArray(k)*cos(2*pi*app.freqArray(k)*tseq+app.phaseArray(k));
                    
                    sinusoidalSignal = sinusoidalSignal + sinSignal;
                end

                app.definedData = sinusoidalSignal;
            end
            
        end

        % Value changed function: AmplitudeEditField_3
        function AmplitudeEditField_3ValueChanged(app, event)
            value = app.AmplitudeEditField_3.Value;
            app.componentAmp=value;
        end

        % Value changed function: FrequencyEditField_3
        function FrequencyEditField_3ValueChanged(app, event)
            value = app.FrequencyEditField_3.Value;
            app.componentFreq=value;
        end

        % Value changed function: PhaseShiftEditField_3
        function PhaseShiftEditField_3ValueChanged(app, event)
            value = app.PhaseShiftEditField_3.Value;
            app.componentPhase=value;
        end

        % Callback function
        function SpectrogramButton_2Pushed(app, event)
            app.state = "define";
            app.Panel.Visible = "on";
            app.DefinePanel.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainMenu = uipanel(app.UIFigure);
            app.MainMenu.Title = 'MainMenu';
            app.RecordButton = uibutton(app.MainMenu, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @openRecordScreen, true);
            app.RecordButton.Text = 'Record';
            app.DefineButton = uibutton(app.MainMenu, 'push');
            app.DefineButton.ButtonPushedFcn = createCallbackFcn(app, @DefineButtonPushed, true);
            app.DefineButton.Text = 'Define';
            app.ImportButton = uibutton(app.MainMenu, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.Label = uilabel(app.MainMenu);
            app.Label.Text = 'Welcome to the quick STFT application. To start, pick a way below to input a signal.';
            app.SecondMenu = uipanel(app.UIFigure);
            app.SecondMenu.Title = 'RecordMenu';
            app.SecondMenu.Visible = 'off';
            app.BackButton = uibutton(app.SecondMenu, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @backToMainMenu, true);
            app.BackButton.Text = 'Back';
            app.StartRecordingButton = uibutton(app.SecondMenu, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @startRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.StopButton = uibutton(app.SecondMenu, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlayButton = uibutton(app.SecondMenu, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.recordingLabel = uilabel(app.SecondMenu);
            app.recordingLabel.Text = 'Before start recording, you may pick the sampling rate above';
            app.NosoundrecordedLabel = uilabel(app.SecondMenu);
            app.NosoundrecordedLabel.Visible = 'off';
            app.NosoundrecordedLabel.Text = 'No sound recorded!';
            app.SampleRateLabel_2 = uilabel(app.SecondMenu);
            app.SampleRateLabel_2.Text = 'Sample Rate:';
            app.SampleRateEditField = uieditfield(app.SecondMenu, 'numeric');
            app.SampleRateEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRateEditFieldValueChanged, true);
            app.SampleRateEditField.Value = 8000;
            app.OKButton = uibutton(app.SecondMenu, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.SpectogramButton_2 = uibutton(app.SecondMenu, 'push');
            app.SpectogramButton_2.ButtonPushedFcn = createCallbackFcn(app, @SpectogramButton_2Pushed, true);
            app.SpectogramButton_2.Text = 'Spectogram';
            app.SampleRateLabel = uilabel(app.SecondMenu);
            app.SampleRateLabel.Text = 'Sample Rate:';
            app.ImportMenuPanel = uipanel(app.UIFigure);
            app.ImportMenuPanel.Title = 'ImportMenu';
            app.ImportMenuPanel.Visible = 'off';
            app.SelectButton = uibutton(app.ImportMenuPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.SelectedfilepathLabel = uilabel(app.ImportMenuPanel);
            app.SelectedfilepathLabel.Visible = 'off';
            app.SelectedfilepathLabel.Text = 'Selected file path:';
            app.sampleRateLabel = uilabel(app.ImportMenuPanel);
            app.sampleRateLabel.Text = '';
            app.PlayButton_2 = uibutton(app.ImportMenuPanel, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Visible = 'off';
            app.PlayButton_2.Text = 'Play';
            app.NowplayingtheaudioLabel = uilabel(app.ImportMenuPanel);
            app.NowplayingtheaudioLabel.Visible = 'off';
            app.NowplayingtheaudioLabel.Text = 'Now, playing the audio...';
            app.BackButton_2 = uibutton(app.ImportMenuPanel, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
            app.SpectogramButton = uibutton(app.ImportMenuPanel, 'push');
            app.SpectogramButton.ButtonPushedFcn = createCallbackFcn(app, @SpectogramButtonPushed, true);
            app.SpectogramButton.Text = 'Spectogram';
            app.STFTWindowPanel = uipanel(app.UIFigure);
            app.STFTWindowPanel.Title = 'STFT Window';
            app.STFTWindowPanel.Visible = 'off';
            app.UIAxes = uiaxes(app.STFTWindowPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AudioAxes = uiaxes(app.STFTWindowPanel);
            title(app.AudioAxes, 'Title')
            xlabel(app.AudioAxes, 'X')
            ylabel(app.AudioAxes, 'Y')
            zlabel(app.AudioAxes, 'Z')
            app.BackButton_3 = uibutton(app.STFTWindowPanel, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButton_3Pushed, true);
            app.BackButton_3.Text = 'Back';
            app.TimeDomainSignalLabel = uilabel(app.STFTWindowPanel);
            app.TimeDomainSignalLabel.Text = 'Time Domain Signal:';
            app.STFToftheSignalLabel = uilabel(app.STFTWindowPanel);
            app.STFToftheSignalLabel.Text = 'STFT of the Signal:';
            app.DefinePanel = uipanel(app.UIFigure);
            app.DefinePanel.Title = 'Define';
            app.DefinePanel.Visible = 'off';
            app.Label_2 = uilabel(app.DefinePanel);
            app.Label_2.Text = 'In this menu, you can generate various waveforms. You may pick the signal type below.';
            app.SinusoidalButton = uibutton(app.DefinePanel, 'push');
            app.SinusoidalButton.ButtonPushedFcn = createCallbackFcn(app, @SinusoidalButtonPushed, true);
            app.SinusoidalButton.Text = 'Sinusoidal';
            app.WindowedSinusoidalButton = uibutton(app.DefinePanel, 'push');
            app.WindowedSinusoidalButton.ButtonPushedFcn = createCallbackFcn(app, @WindowedSinusoidalButtonPushed, true);
            app.WindowedSinusoidalButton.Text = 'Windowed Sinusoidal';
            app.MultiComponentFunctionButton = uibutton(app.DefinePanel, 'push');
            app.MultiComponentFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @MultiComponentFunctionButtonPushed, true);
            app.MultiComponentFunctionButton.Text = 'Multi-Component Function';
            app.SinusoidalSignalExpressionLabel = uilabel(app.DefinePanel);
            app.SinusoidalSignalExpressionLabel.Text = 'Sinusoidal Signal Expression: ';
            app.WindowedSinusoidalSignalExpressionLabel = uilabel(app.DefinePanel);
            app.WindowedSinusoidalSignalExpressionLabel.Text = 'Windowed Sinusoidal Signal Expression: ';
            app.MultiComponentSignalExpressionLabel = uilabel(app.DefinePanel);
            app.MultiComponentSignalExpressionLabel.Text = 'Multi-Component Signal Expression: ';
            app.Image = uiimage(app.DefinePanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2023-12-07 at 13.52.22.png');
            app.Image2 = uiimage(app.DefinePanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2023-12-07 at 13.52.27.png');
            app.Image3 = uiimage(app.DefinePanel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2023-12-07 at 13.54.17.png');
            app.whereLabel = uilabel(app.DefinePanel);
            app.whereLabel.Text = ',where';
            app.Image4 = uiimage(app.DefinePanel);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2023-12-07 at 13.53.47.png');
            app.whereLabel_2 = uilabel(app.DefinePanel);
            app.whereLabel_2.Text = ',where';
            app.Image5 = uiimage(app.DefinePanel);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2023-12-07 at 13.54.22.png');
            app.BackButton_4 = uibutton(app.DefinePanel, 'push');
            app.BackButton_4.ButtonPushedFcn = createCallbackFcn(app, @BackButton_4Pushed, true);
            app.BackButton_4.Text = 'Back';
            app.SpectrogramButton = uibutton(app.DefinePanel, 'push');
            app.SpectrogramButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrogramButtonPushed, true);
            app.SpectrogramButton.Text = 'Spectrogram';
            app.SamplingRateEditFieldLabel = uilabel(app.DefinePanel);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.DefinePanel, 'numeric');
            app.SamplingRateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRateEditFieldValueChanged, true);
            app.SamplingRateEditField.Value = 8000;
            app.SignalDurationsecsEditFieldLabel = uilabel(app.DefinePanel);
            app.SignalDurationsecsEditFieldLabel.Text = 'Signal Duration (secs)';
            app.SignalDurationsecsEditField = uieditfield(app.DefinePanel, 'numeric');
            app.SignalDurationsecsEditField.ValueChangedFcn = createCallbackFcn(app, @SignalDurationsecsEditFieldValueChanged, true);
            app.SignalDurationsecsEditField.Value = 8;
            app.sinusPanel = uipanel(app.DefinePanel);
            app.sinusPanel.Visible = 'off';
            app.SinusoidalFunctionParametersLabel = uilabel(app.sinusPanel);
            app.SinusoidalFunctionParametersLabel.Text = 'Sinusoidal Function Parameters:';
            app.AmplitudeLabel_2 = uilabel(app.sinusPanel);
            app.AmplitudeLabel_2.Text = 'Amplitude:';
            app.AmplitudeEditField = uieditfield(app.sinusPanel, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.AmplitudeEditField.Value = 1;
            app.FrequencyLabel_2 = uilabel(app.sinusPanel);
            app.FrequencyLabel_2.Text = 'Frequency:';
            app.FrequencyEditField = uieditfield(app.sinusPanel, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 1000;
            app.PhaseShiftLabel_2 = uilabel(app.sinusPanel);
            app.PhaseShiftLabel_2.Text = 'Phase Shift:';
            app.PhaseShiftEditField = uieditfield(app.sinusPanel, 'numeric');
            app.PhaseShiftEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseShiftEditFieldValueChanged, true);
            app.PhaseShiftEditField.Value = 30;
            app.OKButton_6 = uibutton(app.sinusPanel, 'push');
            app.OKButton_6.ButtonPushedFcn = createCallbackFcn(app, @OKButton_6Pushed, true);
            app.OKButton_6.Text = 'OK';
            app.Currentvalue1Label = uilabel(app.sinusPanel);
            app.Currentvalue1Label.Text = 'Current value: 1';
            app.Currentvalue1000Label = uilabel(app.sinusPanel);
            app.Currentvalue1000Label.Text = 'Current value: 1000';
            app.Currentvalue30Label = uilabel(app.sinusPanel);
            app.Currentvalue30Label.Text = 'Current value: 30';
            app.windowedSinusPanel = uipanel(app.DefinePanel);
            app.windowedSinusPanel.Visible = 'off';
            app.WindowedSinusoidalFunctionParametersLabel = uilabel(app.windowedSinusPanel);
            app.WindowedSinusoidalFunctionParametersLabel.Text = 'Windowed Sinusoidal Function Parameters:';
            app.AmplitudeLabel = uilabel(app.windowedSinusPanel);
            app.AmplitudeLabel.Text = 'Amplitude:';
            app.AmplitudeEditField_2 = uieditfield(app.windowedSinusPanel, 'numeric');
            app.AmplitudeEditField_2.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField_2ValueChanged, true);
            app.AmplitudeEditField_2.Value = 1;
            app.FrequencyLabel = uilabel(app.windowedSinusPanel);
            app.FrequencyLabel.Text = 'Frequency:';
            app.FrequencyEditField_2 = uieditfield(app.windowedSinusPanel, 'numeric');
            app.FrequencyEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditField_2ValueChanged, true);
            app.FrequencyEditField_2.Value = 1000;
            app.PhaseShiftLabel = uilabel(app.windowedSinusPanel);
            app.PhaseShiftLabel.Text = 'Phase Shift:';
            app.PhaseShiftEditField_2 = uieditfield(app.windowedSinusPanel, 'numeric');
            app.PhaseShiftEditField_2.ValueChangedFcn = createCallbackFcn(app, @PhaseShiftEditField_2ValueChanged, true);
            app.PhaseShiftEditField_2.Value = 30;
            app.StartingTimeLabel = uilabel(app.windowedSinusPanel);
            app.StartingTimeLabel.Text = 'Starting Time:';
            app.StartingTimeEditField = uieditfield(app.windowedSinusPanel, 'numeric');
            app.StartingTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StartingTimeEditFieldValueChanged, true);
            app.WindowLengthLabel = uilabel(app.windowedSinusPanel);
            app.WindowLengthLabel.Text = 'Window Length:';
            app.WindowLengthEditField_2 = uieditfield(app.windowedSinusPanel, 'numeric');
            app.WindowLengthEditField_2.ValueChangedFcn = createCallbackFcn(app, @WindowLengthEditField_2ValueChanged, true);
            app.WindowLengthEditField_2.Value = 1;
            app.OKButton_7 = uibutton(app.windowedSinusPanel, 'push');
            app.OKButton_7.ButtonPushedFcn = createCallbackFcn(app, @OKButton_7Pushed, true);
            app.OKButton_7.Text = 'OK';
            app.WindowFunctionLabel = uilabel(app.windowedSinusPanel);
            app.WindowFunctionLabel.Text = 'Window Function:';
            app.WindowFunctionDropDown = uidropdown(app.windowedSinusPanel);
            app.WindowFunctionDropDown.Items = {'Hamming', 'Rectangular', 'Gaussian', 'Triangular'};
            app.WindowFunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowFunctionDropDownValueChanged, true);
            app.WindowFunctionDropDown.Value = 'Hamming';
            app.CurrentAmplitude1Label = uilabel(app.windowedSinusPanel);
            app.CurrentAmplitude1Label.Text = 'Current Amplitude: 1';
            app.CurrentFrequency1000Label = uilabel(app.windowedSinusPanel);
            app.CurrentFrequency1000Label.Text = 'Current Frequency: 1000';
            app.CurrentPhase30Label = uilabel(app.windowedSinusPanel);
            app.CurrentPhase30Label.Text = 'Current Phase: 30';
            app.CurrentStartingTime0Label = uilabel(app.windowedSinusPanel);
            app.CurrentStartingTime0Label.Text = 'Current Starting Time: 0';
            app.CurrentWindowLength1Label = uilabel(app.windowedSinusPanel);
            app.CurrentWindowLength1Label.Text = 'Current Window Length: 1';
            app.multiComponentPanel = uipanel(app.DefinePanel);
            app.multiComponentPanel.Visible = 'off';
            app.NumberofComponentsLabel = uilabel(app.multiComponentPanel);
            app.NumberofComponentsLabel.Text = 'Number of Components:';
            app.NumberofComponentsEditField = uieditfield(app.multiComponentPanel, 'numeric');
            app.NumberofComponentsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofComponentsEditFieldValueChanged, true);
            app.NumberofComponentsEditField.Value = 1;
            app.sinusPanel_2 = uipanel(app.multiComponentPanel);
            app.sinusPanel_2.Visible = 'off';
            app.Component1ParametersLabel = uilabel(app.sinusPanel_2);
            app.Component1ParametersLabel.Text = 'Component 1 Parameters:';
            app.AmplitudeEditField_3Label = uilabel(app.sinusPanel_2);
            app.AmplitudeEditField_3Label.Text = 'Amplitude:';
            app.AmplitudeEditField_3 = uieditfield(app.sinusPanel_2, 'numeric');
            app.AmplitudeEditField_3.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField_3ValueChanged, true);
            app.AmplitudeEditField_3.Value = 1;
            app.FrequencyEditField_3Label = uilabel(app.sinusPanel_2);
            app.FrequencyEditField_3Label.Text = 'Frequency:';
            app.FrequencyEditField_3 = uieditfield(app.sinusPanel_2, 'numeric');
            app.FrequencyEditField_3.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditField_3ValueChanged, true);
            app.FrequencyEditField_3.Value = 1;
            app.PhaseShiftEditField_3Label = uilabel(app.sinusPanel_2);
            app.PhaseShiftEditField_3Label.Text = 'Phase Shift:';
            app.PhaseShiftEditField_3 = uieditfield(app.sinusPanel_2, 'numeric');
            app.PhaseShiftEditField_3.ValueChangedFcn = createCallbackFcn(app, @PhaseShiftEditField_3ValueChanged, true);
            app.OKButton_9 = uibutton(app.sinusPanel_2, 'push');
            app.OKButton_9.ButtonPushedFcn = createCallbackFcn(app, @OKButton_9Pushed, true);
            app.OKButton_9.Text = 'OK';
            app.OKButton_8 = uibutton(app.multiComponentPanel, 'push');
            app.OKButton_8.ButtonPushedFcn = createCallbackFcn(app, @OKButton_8Pushed, true);
            app.OKButton_8.Text = 'OK';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Panel.Visible = 'off';
            app.WindowTypeButtonGroup = uibuttongroup(app.Panel);
            app.WindowTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WindowTypeButtonGroupSelectionChanged, true);
            app.WindowTypeButtonGroup.Title = 'Window Type';
            app.HammingButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.HammingButton.Value = true;
            app.GaussianButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.RectangularButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.RectangularButton.Text = 'Rectangular';
            app.TriangularButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.TriangularButton.Text = 'Triangular';
            app.WindowLengthEditFieldLabel = uilabel(app.Panel);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.Panel, 'numeric');
            app.WindowLengthEditField.ValueChangedFcn = createCallbackFcn(app, @WindowLengthEditFieldValueChanged, true);
            app.WindowLengthEditField.Value = 1024;
            app.WindowShiftEditFieldLabel = uilabel(app.Panel);
            app.WindowShiftEditFieldLabel.Text = 'Window Shift ';
            app.WindowShiftEditField = uieditfield(app.Panel, 'numeric');
            app.WindowShiftEditField.ValueChangedFcn = createCallbackFcn(app, @WindowShiftEditFieldValueChanged, true);
            app.WindowShiftEditField.Value = 512;
            app.WindowAmplitudeLabel = uilabel(app.Panel);
            app.WindowAmplitudeLabel.Text = 'Window Amplitude ';
            app.WindowAmplitudeEditField = uieditfield(app.Panel, 'numeric');
            app.WindowAmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @WindowAmplitudeEditFieldValueChanged, true);
            app.WindowAmplitudeEditField.Value = 1;
            app.FFTSizesamplesEditFieldLabel = uilabel(app.Panel);
            app.FFTSizesamplesEditFieldLabel.Text = 'FFT Size (samples)';
            app.FFTSizesamplesEditField = uieditfield(app.Panel, 'numeric');
            app.FFTSizesamplesEditField.ValueChangedFcn = createCallbackFcn(app, @FFTSizesamplesEditFieldValueChanged, true);
            app.FFTSizesamplesEditField.Value = 1024;
            app.OKButton_2 = uibutton(app.Panel, 'push');
            app.OKButton_2.ButtonPushedFcn = createCallbackFcn(app, @OKButton_2Pushed, true);
            app.OKButton_2.Text = 'OK';
            app.OKButton_3 = uibutton(app.Panel, 'push');
            app.OKButton_3.ButtonPushedFcn = createCallbackFcn(app, @OKButton_3Pushed, true);
            app.OKButton_3.Text = 'OK';
            app.OKButton_4 = uibutton(app.Panel, 'push');
            app.OKButton_4.ButtonPushedFcn = createCallbackFcn(app, @OKButton_4Pushed, true);
            app.OKButton_4.Text = 'OK';
            app.OKButton_5 = uibutton(app.Panel, 'push');
            app.OKButton_5.ButtonPushedFcn = createCallbackFcn(app, @OKButton_5Pushed, true);
            app.OKButton_5.Text = 'OK';
            app.Settodefaultvalue1024Label = uilabel(app.Panel);
            app.Settodefaultvalue1024Label.Text = 'Set to default value: 1024';
            app.Settodefaultvalue512Label_2 = uilabel(app.Panel);
            app.Settodefaultvalue512Label_2.Text = 'Set to default value: 512';
            app.Settodefaultvalue1Label_2 = uilabel(app.Panel);
            app.Settodefaultvalue1Label_2.Text = 'Set to default value: 1';
            app.Settodefaultvalue1024Label_2 = uilabel(app.Panel);
            app.Settodefaultvalue1024Label_2.Text = 'Set to default value: 1024';
            app.PlotButton = uibutton(app.Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.BackButton_5 = uibutton(app.Panel, 'push');
            app.BackButton_5.ButtonPushedFcn = createCallbackFcn(app, @BackButton_5Pushed, true);
            app.BackButton_5.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stftapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end